classdef capturesample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        CaptureLampLabel      matlab.ui.control.Label
        CaptureLamp           matlab.ui.control.Lamp
        StatusLampLabel       matlab.ui.control.Label
        StatusLamp            matlab.ui.control.Lamp
        PortListBoxLabel      matlab.ui.control.Label
        PortListBox           matlab.ui.control.ListBox
        LabelEditFieldLabel   matlab.ui.control.Label
        LabelEditField        matlab.ui.control.EditField
        CaptureButton         matlab.ui.control.Button
        UpdateListButton      matlab.ui.control.Button
        PeriodEditFieldLabel  matlab.ui.control.Label
        PeriodEditField       matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        enose ENoseDAQ % Description
        isCapturing logical % boolean value to determine whether we are currently capturing
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PortListBox.Items = cellstr(seriallist);
        end

        % Value changed function: PortListBox
        function PortListBoxValueChanged(app, event)
            % Cannot change COM-port while capturing
            if app.isCapturing == 1
                return
            end
            
            % If there currently is an enose initialized, delete it
            if isvalid(app.enose)
                app.enose.delete()
            end
            
            % Connect ENoseDAQ
            app.StatusLamp.Color = [1 1 0];
            drawnow;
            e = ENoseDAQ(app.PortListBox.Value,0,0);
            e.setSampleRate(300); 
            e.setSensitivity(1,128);
            e.setSensitivity(2,128);
            e.setSensitivity(3,128);
            e.setSensitivity(4,128);
            e.setSensitivity(5,128);
            e.setSensitivity(6,128);
            e.setSensitivity(7,128);
            e.setSensitivity(8,128);
            app.StatusLamp.Color = [0 1 0];
            
            app.enose = e;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.enose);  
            delete(app);
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            app.PortListBox.Items = cellstr(seriallist);
        end

        % Button pushed function: CaptureButton
        function capture(app, event)
            % Do not capture if we aren't connected to a session
            if ~IsValid(app.enose)
                app.CaptureLamp.Color = [1 1 0];
                return
            end
            
            % Fetch the specified amount of time
            p = app.PeriodEditField.Value;
            if p < 1 || p > 600 
               app.CaptureLamp.Color = [1 1 0]; 
            end
            
            % Fetch the label
            label = app.LabelEditField.Value;
            
            % Capture data
            app.isCapturing = true;
            app.CaptureLamp.Color = [0 1 0];
            drawnow;
            data = app.enose.capture(p);
            app.CaptureLamp.Color = [1 0 0];
            app.isCapturing = false;
            
            % Save data
            csvwrite(datestr(now,'yyyy-mm-dd_HH-MM-SS') + "_" + label + ".csv",data);
            
            % Plot the data
            t = data(:,1);
            sensors = 5 * data(:,2:9) / 1024;
            plot(app.UIAxes, t,sensors);
            axis(app.UIAxes, [min(t) max(t) min(sensors(:))*0.9 max(sensors(:))*1.1]);
            legend(app.UIAxes, "MQ-2","MQ-3","MQ-4","MQ-5","MQ-6","MQ-7","MQ-8","MQ-138");
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 0.5 1];
            app.UIAxes.YTick = [0 0.5 1];
            app.CaptureLampLabel = uilabel(app.UIFigure);
            app.CaptureLampLabel.Text = 'Capture';
            app.CaptureLamp = uilamp(app.UIFigure);
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.PortListBoxLabel = uilabel(app.UIFigure);
            app.PortListBoxLabel.Text = 'Port';
            app.PortListBox = uilistbox(app.UIFigure);
            app.PortListBox.Items = {};
            app.PortListBox.ValueChangedFcn = createCallbackFcn(app, @PortListBoxValueChanged, true);
            app.PortListBox.Value = {};
            app.LabelEditFieldLabel = uilabel(app.UIFigure);
            app.LabelEditField = uieditfield(app.UIFigure, 'text');
            app.CaptureButton = uibutton(app.UIFigure, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @capture, true);
            app.CaptureButton.Text = 'Capture';
            app.UpdateListButton = uibutton(app.UIFigure, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.PeriodEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.PeriodEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = capturesample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end