classdef read < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        Label                    matlab.ui.control.Label
        Switch                   matlab.ui.control.Switch
        TimescaleSlider          matlab.ui.control.Slider
        SensitivityControlPanel  matlab.ui.container.Panel
        Slider                   matlab.ui.control.Slider
        Slider_2                 matlab.ui.control.Slider
        Slider_3                 matlab.ui.control.Slider
        Slider_4                 matlab.ui.control.Slider
        Slider_5                 matlab.ui.control.Slider
        Slider_6                 matlab.ui.control.Slider
        Slider_7                 matlab.ui.control.Slider
        Slider_8                 matlab.ui.control.Slider
        StatusLampLabel          matlab.ui.control.Label
        StatusLamp               matlab.ui.control.Lamp
        UpdatePortsButton        matlab.ui.control.Button
        PortListBox              matlab.ui.control.ListBox
    end


    properties (Access = private)
        enose ENoseDAQ; % enose DAQ object
        
        line1;
        line2;
        line3;
        line4;
        line5;
        line6;
        line7;
        line8;
    end

    methods (Access = private)
   
        function readData(app)
                data = app.enose.read();
               
               if numel(data) ~= 9 || data(1) <= 0 
                   return;
               end
                
%                Add points to the animated line
               addpoints(app.line1,data(1),data(2));
               addpoints(app.line2,data(1),data(3));
               addpoints(app.line3,data(1),data(4));
               addpoints(app.line4,data(1),data(5));
               addpoints(app.line5,data(1),data(6));
               addpoints(app.line6,data(1),data(7));
               addpoints(app.line7,data(1),data(8));
               addpoints(app.line8,data(1),data(9));
               
               app.updateFigure();
        end
        
        function e = getEdge(~,values,threshold)
            for i = 1:numel(values)
               v = values(i);
               if v > threshold
                   e = i;
                   return;
               end
            end
            
            e=v(end);
        end
        
        function updateFigure(app)
                % Figure out a axis that displays all fetched data
               [t,y1] = getpoints(app.line1);
               
               if numel(t) < 1 
                   return;
               end
               
               [~,y2] = getpoints(app.line2);
               [~,y3] = getpoints(app.line3);
               [~,y4] = getpoints(app.line4);
               [~,y5] = getpoints(app.line5);
               [~,y6] = getpoints(app.line6);
               [~,y7] = getpoints(app.line7);
               [~,y8] = getpoints(app.line8);
                             
                % X axis setup
               maxX = max(t);
               
                % Max time visible
               if app.TimescaleSlider.Value == 0
                   minX = min(t);
               else
                   xLength = app.TimescaleSlider.Value * 30;  % 30 seconds
                   minX = maxX - xLength; 
               end
               
                % Edge case
               if minX == maxX
                   minX = 0;
               end
               
                % Figure out max Y
               if minX > 0 && maxX > 0
                    % We want to know only the maximum value of the data on our
                    % screen
                   edg = app.getEdge(t,minX);
                   maxY = max([y1(edg:end) y2(edg:end) y3(edg:end) y4(edg:end) y5(edg:end) y6(edg:end) y7(edg:end) y8(edg:end)]);
               else
                   maxY = max([y1 y2 y3 y4 y5 y6 y7 y8]);
               end
                   
               axis(app.UIAxes,[minX maxX 0 maxY*1.1]);
               
               drawnow limitrate; 
        end
        
        function updatePortsList(app)
            app.PortListBox.Items = cellstr(seriallist);
        end
        
        function uploadSensitivity(app)
            app.enose.setSensitivity(1,app.Slider.Value);
            app.enose.setSensitivity(2,app.Slider_2.Value);
            app.enose.setSensitivity(3,app.Slider_3.Value);
            app.enose.setSensitivity(4,app.Slider_3.Value);
            app.enose.setSensitivity(5,app.Slider_5.Value);
            app.enose.setSensitivity(6,app.Slider_6.Value);
            app.enose.setSensitivity(7,app.Slider_7.Value);
            app.enose.setSensitivity(8,app.Slider_8.Value); 
        end
        
        function serialCallback(app)
            if app.enose.isStarted()
                while isvalid(app.enose) && app.enose.measurementsAvailable() > 0
                    app.readData();
                end
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.line1 = animatedline(app.UIAxes,'Color',[0, 0.4470, 0.7410]);
            app.line2 = animatedline(app.UIAxes,'Color',[0.8500, 0.3250, 0.0980]);
            app.line3 = animatedline(app.UIAxes,'Color',[0.9290, 0.6940, 0.1250]);
            app.line4 = animatedline(app.UIAxes,'Color',[0.4940, 0.1840, 0.5560]);
            app.line5 = animatedline(app.UIAxes,'Color',[0.4660, 0.6740, 0.1880]);
            app.line6 = animatedline(app.UIAxes,'Color',[0.3010, 0.7450, 0.9330]);
            app.line7 = animatedline(app.UIAxes,'Color',[0.6350, 0.0780, 0.1840]);
            app.line8 = animatedline(app.UIAxes,'Color',[0.75, 0.75, 0]);
            
            app.updatePortsList();
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
           
            if strcmp(app.Switch.Value,'Off')
                app.StatusLamp.Color = [1 1 0];
                app.enose.reset();
                app.StatusLamp.Color = [0 0 1];
                app.enose.clear();
                app.enose.delete();
                
                app.StatusLamp.Color = [1 0 0];
                
                return;
            end
            
            app.StatusLamp.Color = [1 1 0];
            app.line1.clearpoints();
                app.line2.clearpoints();
                app.line3.clearpoints();
                app.line4.clearpoints();
                app.line5.clearpoints();
                app.line6.clearpoints();
                app.line7.clearpoints();
                app.line8.clearpoints();
                
            app.enose = ENoseDAQ(app.PortListBox.Value,true,@app.serialCallback);
            app.enose.setSampleRate(50);
            
            app.uploadSensitivity();
            
            app.enose.start();
            
            app.StatusLamp.Color = [0 1 0];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.enose)
               delete(app.enose);
            end
            
            delete(app);
        end

        % Value changing function: TimescaleSlider
        function TimescaleSliderValueChanging(app, event)
            app.updateFigure();
        end

        % Button pushed function: UpdatePortsButton
        function UpdatePortsButtonPushed(app, event)
            app.updatePortsList();
        end

        % Callback function
        function UploadButtonPushed(app, event)
            app.uploadSensitivity();
        end

        % Value changed function: Slider, Slider_2, Slider_3, 
        % Slider_4, Slider_5, Slider_6, Slider_7, Slider_8
        function SliderValueChanged(app, event)
            value = event.Source.Value;
            
            switch (event.Source)
                case app.Slider
                    app.enose.setSensitivity(1,value);
                case app.Slider_2
                    app.enose.setSensitivity(2,value);
                case app.Slider_3
                    app.enose.setSensitivity(3,value);
                case app.Slider_4
                    app.enose.setSensitivity(4,value);       
                case app.Slider_5
                    app.enose.setSensitivity(5,value);        
                case app.Slider_6
                    app.enose.setSensitivity(6,value);            
                case app.Slider_7
                    app.enose.setSensitivity(7,value);                
                case app.Slider_8
                    app.enose.setSensitivity(8,value);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ENoseData')
            xlabel(app.UIAxes, 'Time t [s]')
            ylabel(app.UIAxes, 'ADC reading [0-1024]')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.TimescaleSlider = uislider(app.UIFigure);
            app.TimescaleSlider.Limits = [0 1];
            app.TimescaleSlider.MajorTicks = [0 0.5 1];
            app.TimescaleSlider.MajorTickLabels = {'', '', ''};
            app.TimescaleSlider.ValueChangingFcn = createCallbackFcn(app, @TimescaleSliderValueChanging, true);
            app.SensitivityControlPanel = uipanel(app.UIFigure);
            app.SensitivityControlPanel.Title = 'Sensitivity Control';
            app.Slider = uislider(app.SensitivityControlPanel);
            app.Slider.Limits = [0 255];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 128;
            app.Slider_2 = uislider(app.SensitivityControlPanel);
            app.Slider_2.Limits = [0 255];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_2.Value = 128;
            app.Slider_3 = uislider(app.SensitivityControlPanel);
            app.Slider_3.Limits = [0 255];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_3.Value = 128;
            app.Slider_4 = uislider(app.SensitivityControlPanel);
            app.Slider_4.Limits = [0 255];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_4.Value = 128;
            app.Slider_5 = uislider(app.SensitivityControlPanel);
            app.Slider_5.Limits = [0 255];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_5.Value = 128;
            app.Slider_6 = uislider(app.SensitivityControlPanel);
            app.Slider_6.Limits = [0 255];
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_6.Value = 128;
            app.Slider_7 = uislider(app.SensitivityControlPanel);
            app.Slider_7.Limits = [0 255];
            app.Slider_7.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_7.Value = 128;
            app.Slider_8 = uislider(app.SensitivityControlPanel);
            app.Slider_8.Limits = [0 255];
            app.Slider_8.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_8.Value = 128;
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.UpdatePortsButton = uibutton(app.UIFigure, 'push');
            app.UpdatePortsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePortsButtonPushed, true);
            app.UpdatePortsButton.Text = 'Update Ports';
            app.PortListBox = uilistbox(app.UIFigure);
            app.PortListBox.Items = {};
            app.PortListBox.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = read

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end